classdef rouse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        umsSliderLabel             matlab.ui.control.Label
        usSlider                   matlab.ui.control.Slider
        wmsSliderLabel             matlab.ui.control.Label
        wsSlider                   matlab.ui.control.Slider
        PlotButton                 matlab.ui.control.Button
        RousenumberEditFieldLabel  matlab.ui.control.Label
        RouseField                 matlab.ui.control.NumericEditField
        z0001hisassumedconstantDtakenasequaltoRousenumberLabel  matlab.ui.control.Label
        logscaleSwitchLabel        matlab.ui.control.Label
        logscaleSwitch             matlab.ui.control.Switch
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PlotButton, logscaleSwitch, usSlider, 
        % usSlider, wsSlider, wsSlider
        function PlotButtonPushed(app, event)
           wf = app.wsSlider.Value;
           us = app.usSlider.Value;
           k = 0.4;
           z0 = 0.01; %assume z0 = 0.01 h
           
           expo = -wf/k/us;
           %non dimensional concentration:
           z_as = 0.01:0.01:1;
           C_as = exp(expo*z_as);
           C_ln = (z_as./z0).^expo;
           %Rouse profile
           C_r = ((z_as./(1 - z_as)).*((1 - z0)./z0)).^expo;
           
           app.RouseField.Value = -expo;
           
           plot(app.UIAxes,C_ln,z_as,'-k',...
               'LineWidth',2)
           hold(app.UIAxes,'on')
           plot(app.UIAxes,C_as,z_as,'-b','LineWidth',2)
           plot(app.UIAxes,C_r,z_as,'-r','LineWidth',2)
           ylim(app.UIAxes,[0 1]);
           legend(app.UIAxes,'constant D','linear D','Rouse profile')
           
           value = app.logscaleSwitch.Value;
           if strcmp(value,'On')
               app.UIAxes.XScale = 'log';
               xlim(app.UIAxes,[0.00001 1])
           else
               app.UIAxes.XScale = 'linear';
               xlim(app.UIAxes,[0 1])
           end
           
           hold(app.UIAxes,'off')
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.umsSliderLabel = uilabel(app.UIFigure);
            app.umsSliderLabel.Text = 'u* (m/s)';
            app.usSlider = uislider(app.UIFigure);
            app.usSlider.Limits = [0 0.3];
            app.usSlider.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.usSlider.ValueChangingFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.usSlider.Value = 0.15;
            app.wmsSliderLabel = uilabel(app.UIFigure);
            app.wmsSliderLabel.Text = 'w (m/s)';
            app.wsSlider = uislider(app.UIFigure);
            app.wsSlider.Limits = [0.001 1];
            app.wsSlider.MajorTicks = [0.001 0.201 0.401 0.601 1];
            app.wsSlider.MajorTickLabels = {'0.001', '', '', '', '1'};
            app.wsSlider.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.wsSlider.ValueChangingFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.wsSlider.MinorTicks = [0.001 0.041 0.081 0.121 0.161 0.201 0.241 0.281 0.321 0.361 0.401 0.441 0.481 0.521 0.561 0.601 0.641 0.681 0.721 0.761 0.801 0.841 0.881 0.921 1];
            app.wsSlider.Value = 0.01;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RousenumberEditFieldLabel = uilabel(app.UIFigure);
            app.RousenumberEditFieldLabel.Text = 'Rouse number';
            app.RouseField = uieditfield(app.UIFigure, 'numeric');
            app.RouseField.Editable = 'off';
            app.z0001hisassumedconstantDtakenasequaltoRousenumberLabel = uilabel(app.UIFigure);
            app.z0001hisassumedconstantDtakenasequaltoRousenumberLabel.Text = '*z0 = 0.01h is assumed; constant D taken as equal to Rouse number';
            app.logscaleSwitchLabel = uilabel(app.UIFigure);
            app.logscaleSwitchLabel.Text = 'log scale';
            app.logscaleSwitch = uiswitch(app.UIFigure, 'slider');
            app.logscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Concentration profiles')
            xlabel(app.UIAxes, 'C/C0')
            ylabel(app.UIAxes, 'z/h')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rouse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end